classdef PPGFeat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PPGFeatUIFigure             matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Panel                       matlab.ui.container.Panel
        PanelGridLayout             matlab.ui.container.GridLayout
        SegCombSQIEdit              matlab.ui.control.NumericEditField
        CorrelationLabel_4          matlab.ui.control.Label
        SegSkewSQIEditField         matlab.ui.control.NumericEditField
        SkewnessLabel_2             matlab.ui.control.Label
        SegCorrSQIEditField         matlab.ui.control.NumericEditField
        CorrelationLabel_3          matlab.ui.control.Label
        CycleCombSQIEditField       matlab.ui.control.NumericEditField
        CorrelationLabel_2          matlab.ui.control.Label
        CycleSkewSQIEditField       matlab.ui.control.NumericEditField
        SkewnessLabel               matlab.ui.control.Label
        CycleCorrSQIEditField       matlab.ui.control.NumericEditField
        CorrelationLabel            matlab.ui.control.Label
        PlotpressedLamp             matlab.ui.control.Lamp
        PlotpressedLampLabel        matlab.ui.control.Label
        SegmentSQILabel             matlab.ui.control.Label
        GenerateoutputLabel         matlab.ui.control.Label
        SavecsvButton               matlab.ui.control.Button
        ProcesseveryentrywithoutmanualinputLabel  matlab.ui.control.Label
        ProcessAllButton            matlab.ui.control.Button
        CycleSQILabel               matlab.ui.control.Label
        SavematButton               matlab.ui.control.Button
        GotonextwaveformLabel       matlab.ui.control.Label
        NextButton                  matlab.ui.control.Button
        UpdatefeaturepointsusingmodifieduserdefinedvaluesLabel  matlab.ui.control.Label
        UpdateButton                matlab.ui.control.Button
        Plotallwaveformsusingmin1andmin2Label  matlab.ui.control.Label
        PlotButton                  matlab.ui.control.Button
        InputPanel                  matlab.ui.container.Panel
        InputPanelGridLayout        matlab.ui.container.GridLayout
        InvertLabel                 matlab.ui.control.Label
        InvertCheck                 matlab.ui.control.CheckBox
        HzLabel_2                   matlab.ui.control.Label
        LoadFolderButton            matlab.ui.control.Button
        LoadFileButton              matlab.ui.control.Button
        SegmentEditField            matlab.ui.control.NumericEditField
        SegmentEditFieldLabel       matlab.ui.control.Label
        ResampleEditField           matlab.ui.control.NumericEditField
        ResampleEditFieldLabel      matlab.ui.control.Label
        FHEditField                 matlab.ui.control.NumericEditField
        FHEditFieldLabel            matlab.ui.control.Label
        FLEditField                 matlab.ui.control.NumericEditField
        F1Label                     matlab.ui.control.Label
        SamplingfreqEditField       matlab.ui.control.NumericEditField
        SamplingfreqEditFieldLabel  matlab.ui.control.Label
        SegmentCheck                matlab.ui.control.CheckBox
        ResampleCheck               matlab.ui.control.CheckBox
        EnterSamplingfreqfilterfreqLabel  matlab.ui.control.Label
        sLabel                      matlab.ui.control.Label
        HzLabel                     matlab.ui.control.Label
        RawPPG_Panel                matlab.ui.container.Panel
        RawPPG_GridLayout           matlab.ui.container.GridLayout
        UIAxes4                     matlab.ui.control.UIAxes
        FilteredPPG_Panel           matlab.ui.container.Panel
        FilteredPPG_GridLayout      matlab.ui.container.GridLayout
        EntryEditField              matlab.ui.control.EditField
        EntryEditFieldLabel         matlab.ui.control.Label
        SegmentTextArea             matlab.ui.control.TextArea
        SegmentTextAreaLabel        matlab.ui.control.Label
        Min1EditFieldLabel          matlab.ui.control.Label
        Min1EditField               matlab.ui.control.NumericEditField
        Min2EditFieldLabel          matlab.ui.control.Label
        Min2EditField               matlab.ui.control.NumericEditField
        UIAxes                      matlab.ui.control.UIAxes
        PPG_Panel                   matlab.ui.container.Panel
        PPG_GridLayout              matlab.ui.container.GridLayout
        DpEditField                 matlab.ui.control.NumericEditField
        SLabel                      matlab.ui.control.Label
        DnEditField                 matlab.ui.control.NumericEditField
        dnLabel                     matlab.ui.control.Label
        SpEditField                 matlab.ui.control.NumericEditField
        DLabel                      matlab.ui.control.Label
        OnEditField                 matlab.ui.control.NumericEditField
        onLabel                     matlab.ui.control.Label
        OffEditField                matlab.ui.control.NumericEditField
        SLabel_2                    matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        VPG_Panel                   matlab.ui.container.Panel
        VPG_GirdLayout              matlab.ui.container.GridLayout
        uLabel                      matlab.ui.control.Label
        wEditField                  matlab.ui.control.NumericEditField
        zLabel                      matlab.ui.control.Label
        vEditField                  matlab.ui.control.NumericEditField
        yLabel                      matlab.ui.control.Label
        xEditField                  matlab.ui.control.NumericEditField
        xLabel                      matlab.ui.control.Label
        uEditField                  matlab.ui.control.NumericEditField
        UIAxes6                     matlab.ui.control.UIAxes
        APG_Panel                   matlab.ui.container.Panel
        APG_GridLayout              matlab.ui.container.GridLayout
        fLabel                      matlab.ui.control.Label
        dEditFieldLabel             matlab.ui.control.Label
        dEditField                  matlab.ui.control.NumericEditField
        cmLabel                     matlab.ui.control.Label
        cEditField                  matlab.ui.control.NumericEditField
        fEditField                  matlab.ui.control.NumericEditField
        fEditFieldLabel             matlab.ui.control.Label
        eEditField                  matlab.ui.control.NumericEditField
        eEditFieldLabel             matlab.ui.control.Label
        bEditField                  matlab.ui.control.NumericEditField
        bEditFieldLabel             matlab.ui.control.Label
        aEditField                  matlab.ui.control.NumericEditField
        aEditFieldLabel             matlab.ui.control.Label
        Yes0NoLabel                 matlab.ui.control.Label
        canddpresentEditField       matlab.ui.control.NumericEditField
        canddpresentEditFieldLabel  matlab.ui.control.Label
        UIAxes5                     matlab.ui.control.UIAxes
        JPG_Panel                   matlab.ui.container.Panel
        JPG_GridLayout              matlab.ui.container.GridLayout
        UIAxes3                     matlab.ui.control.UIAxes
    end

    properties (Access = private)
        PPGAnalObject
    end

    methods (Access = private)

        function PlotPPG(app)
            Entry1 = num2str(app.PPGAnalObject.entry_idx);
            Entry2 = num2str(app.PPGAnalObject.num_entries);
            Entry = Entry1 + " of " + Entry2;
            app.EntryEditField.Value = Entry;

            plot(app.UIAxes4, app.PPGAnalObject.loaded_data(app.PPGAnalObject.data_idx,:))  %Plot first RAW waveform

            %show data numbr in the text area 
            Dat1 = num2str(app.PPGAnalObject.size_data(1) * app.PPGAnalObject.num_seg);
            Dat2 = num2str(app.PPGAnalObject.total_seg_idx);
            Dat = Dat2 + " of " + Dat1;
            app.SegmentTextArea.Value = Dat;

            %plot the filtered PPG and show the maxima and minima
            filtered_data = app.PPGAnalObject.PPG_filtered(app.PPGAnalObject.total_seg_idx ,:);
            PPG = plot(app.UIAxes, filtered_data ); %Plot filtered PPG
            %addToolbarExplorationButtons(PPG);
            grid(app.UIAxes, "on")

            [min1, min2, cycle_corr, cycle_skew, seg_corr, seg_skew, cycle_qual, seg_qual] = app.PPGAnalObject.FindBestCycle();
            
            if (isnan(min1))
                msgbox('Could not detect any cycles in segment', 'Error', 'error')
            else
                app.Min1EditField.Value = min1;
                app.Min2EditField.Value = min2;
                app.CycleCorrSQIEditField.Value = cycle_corr;
                app.CycleSkewSQIEditField.Value = cycle_skew;
                app.SegCorrSQIEditField.Value = seg_corr;
                app.SegSkewSQIEditField.Value = seg_skew;
                app.CycleCombSQIEditField.Value = cycle_qual;
                app.SegCombSQIEdit.Value = seg_qual;

                hold(app.UIAxes, "on")
                plot(app.UIAxes, app.Min1EditField.Value, filtered_data(app.Min1EditField.Value), '.', 'MarkerSize',12, 'Color','k')
                plot(app.UIAxes, app.Min2EditField.Value, filtered_data(app.Min2EditField.Value), '.', 'MarkerSize',12, 'Color','k') 
                hold(app.UIAxes, "off")

                %datatip(PPG, app.Min1EditField.Value,filtered_data(app.Min1EditField.Value),'FontSize',8);
                %datatip(PPG, app.Min2EditField.Value,filtered_data(app.Min2EditField.Value),'FontSize',8);
            end
        end
        
        function PlotFiducial(app, PPGAnal)
            seg_index = PPGAnal.total_seg_idx;

            %Update fields after with ppg_anal values
            if ~isnan(PPGAnal.OnSpDnDpOff_time(1))
                app.OnEditField.Value = PPGAnal.OnSpDnDpOff_time(1);
            else
                app.OnEditField.Value = [];
            end
            if ~isnan(PPGAnal.OnSpDnDpOff_time(2))
                app.SpEditField.Value = PPGAnal.OnSpDnDpOff_time(2);
            else
                app.SpEditField.Value = [];
            end
            if ~isnan(PPGAnal.OnSpDnDpOff_time(3))
                app.DnEditField.Value = PPGAnal.OnSpDnDpOff_time(3);
            else
                app.DnEditField.Value = [];
            end
            if ~isnan(PPGAnal.OnSpDnDpOff_time(4))
                app.DpEditField.Value = PPGAnal.OnSpDnDpOff_time(4);
            else
                app.DpEditField.Value = [];
            end
            if ~isnan(PPGAnal.OnSpDnDpOff_time(5))
                app.OffEditField.Value = PPGAnal.OnSpDnDpOff_time(5);
            else
                app.OffEditField.Value = [];
            end

            if ~isnan(PPGAnal.uxvw_time(1))
                app.uEditField.Value = PPGAnal.uxvw_time(1);
            else
                app.uEditField.Value = [];
            end
            if ~isnan(PPGAnal.uxvw_time(2))
                app.xEditField.Value = PPGAnal.uxvw_time(2);
            else
                app.xEditField.Value = [];
            end
            if ~isnan(PPGAnal.uxvw_time(3))
                app.vEditField.Value = PPGAnal.uxvw_time(3);
            else
                app.vEditField.Value = [];
            end
            if ~isnan(PPGAnal.uxvw_time(4))
                app.wEditField.Value = PPGAnal.uxvw_time(4);
            else
                app.wEditField.Value = [];
            end

            if ~isnan(PPGAnal.abcdef_time(1))
                app.aEditField.Value = PPGAnal.abcdef_time(1);
            else
                app.aEditField.Value = [];
            end
            if ~isnan(PPGAnal.abcdef_time(2))
                app.bEditField.Value = PPGAnal.abcdef_time(2);
            else
                app.bEditField.Value = [];
            end
            if ~isnan(PPGAnal.abcdef_time(3))
                app.cEditField.Value = PPGAnal.abcdef_time(3);
            else
                app.cEditField.Value = [];
            end
            if ~isnan(PPGAnal.abcdef_time(4))
                app.dEditField.Value = PPGAnal.abcdef_time(4);
            else
                app.dEditField.Value = [];
            end
            if ~isnan(PPGAnal.abcdef_time(5))
                app.eEditField.Value = PPGAnal.abcdef_time(5);
            else
                app.eEditField.Value = [];
            end
            if ~isnan(PPGAnal.abcdef_time(6))
                app.fEditField.Value = PPGAnal.abcdef_time(6);
            else
                app.fEditField.Value = [];
            end

            app.canddpresentEditField.Value = PPGAnal.c_d_pres;

            %plot the PPG segment
            P_seg = plot(app.UIAxes2,PPGAnal.seg);
            grid(app.UIAxes2,"on")

            line(app.UIAxes2,[0 length(PPGAnal.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment

            hold(app.UIAxes2, "on")
            plot(app.UIAxes2, PPGAnal.OnSpDnDpOff_time, PPGAnal.OnSpDnDpOff, '.', 'MarkerSize',12, 'Color','k') 
            hold(app.UIAxes2, "off")

            %for i = 1:5
            %    if (~isnan(PPGAnal.OnSpDnDpOff_time(i)))
            %        datatip(P_seg, PPGAnal.OnSpDnDpOff_time(i),PPGAnal.OnSpDnDpOff(i),'FontSize',8);
            %    end
            %end

            %plot VPG
            P_vpg = plot(app.UIAxes6, PPGAnal.VPG);
            grid(app.UIAxes6,"on")

            line(app.UIAxes6,[0 length(PPGAnal.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment

            hold(app.UIAxes6, "on")
            plot(app.UIAxes6, PPGAnal.uxvw_time, PPGAnal.uxvw, '.', 'MarkerSize',12, 'Color','k') 
            hold(app.UIAxes6, "off")

            %for i = 1:4
            %    if (~isnan(PPGAnal.uxvw_time(i)))
            %        datatip(P_vpg, PPGAnal.uxvw_time(i),PPGAnal.uxvw(i),'FontSize',8);
            %    end
            %end

            %plot APG
            P_apg = plot(app.UIAxes5, PPGAnal.APG);
            grid(app.UIAxes5,"on")

            line(app.UIAxes5,[0 length(PPGAnal.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment    

            hold(app.UIAxes5, "on")
            plot(app.UIAxes5, PPGAnal.abcdef_time, PPGAnal.abcdef, '.', 'MarkerSize',12, 'Color','k') 
            hold(app.UIAxes5, "off")
        
            %for i = 1:6
            %    if (~isnan(PPGAnal.abcdef_time(i)))
            %        datatip(P_apg, PPGAnal.abcdef_time(i),PPGAnal.abcdef(i),'FontSize',8);
            %    end
            %end

            %plot JPG
            plot(app.UIAxes3, PPGAnal.JPG)
            grid(app.UIAxes3,"on")

            line(app.UIAxes3,[0 length(PPGAnal.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment
        end    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PPGAnalObject = ppg_anal;
            PPGFeatUIFigureThemeChanged(app);
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)

            if (app.ResampleCheck.Value == true)
                res = app.PPGAnalObject.LoadPPG(app.SamplingfreqEditField.Value, app.ResampleEditField.Value, app.FLEditField.Value, ...
                    app.FHEditField.Value, app.SegmentCheck.Value, ceil(app.SegmentEditField.Value * 1000), app.InvertCheck.Value);
            else
                res = app.PPGAnalObject.LoadPPG(app.SamplingfreqEditField.Value, app.SamplingfreqEditField.Value, app.FLEditField.Value, ...
                    app.FHEditField.Value, app.SegmentCheck.Value, ceil(app.SegmentEditField.Value * 1000), app.InvertCheck.Value);
            end
            
            if (res)
                cla(app.UIAxes);
                cla(app.UIAxes2);
                cla(app.UIAxes3);
                cla(app.UIAxes4);
                cla(app.UIAxes5);
                cla(app.UIAxes6);
                app.PlotPPG();
            end
        end

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            if (app.ResampleCheck.Value == true)
                res = app.PPGAnalObject.LoadDirectory(app.SamplingfreqEditField.Value, app.ResampleEditField.Value, app.FLEditField.Value, ...
                    app.FHEditField.Value, app.SegmentCheck.Value, ceil(app.SegmentEditField.Value * 1000), app.InvertCheck.Value);
            else
                res = app.PPGAnalObject.LoadDirectory(app.SamplingfreqEditField.Value, app.SamplingfreqEditField.Value, app.FLEditField.Value, ...
                    app.FHEditField.Value, app.SegmentCheck.Value, ceil(app.SegmentEditField.Value * 1000), app.InvertCheck.Value);
            end
            if (res)
                cla(app.UIAxes);
                cla(app.UIAxes2);
                cla(app.UIAxes3);
                cla(app.UIAxes4);
                cla(app.UIAxes5);
                cla(app.UIAxes6);
                app.PlotPPG();
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            PPGAnal = app.PPGAnalObject;

            app.PlotpressedLamp.Color = 'g'; %to show user that the plot button has been pressed

            filtered_data = app.PPGAnalObject.PPG_filtered(app.PPGAnalObject.total_seg_idx ,:);

            plot(app.UIAxes, filtered_data ); %Plot filtered PPG
            grid(app.UIAxes, "on")

            hold(app.UIAxes, "on")
            plot(app.UIAxes, app.Min1EditField.Value, filtered_data(app.Min1EditField.Value), '.', 'MarkerSize',12, 'Color','k')
            plot(app.UIAxes, app.Min2EditField.Value, filtered_data(app.Min2EditField.Value), '.', 'MarkerSize',12, 'Color','k') 
            hold(app.UIAxes, "off")

            result = PPGAnal.CalculateFiducial(app.Min1EditField.Value, app.Min2EditField.Value);

            if (~result)
                msgbox('Could not calcualte all fiducial points for segment', 'Error', 'error')
            elseif (anynan(PPGAnal.feature.total(PPGAnal.total_seg_idx,:)))
                msgbox('Could not calcualte all extra biomarkers for segment', 'Error', 'error')
            end

            app.PlotFiducial(PPGAnal);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            PPGAnal = app.PPGAnalObject;

            updateValues = {app.OnEditField.Value, app.SpEditField.Value, app.DnEditField.Value, app.DpEditField.Value, app.OffEditField.Value, ...
                app.uEditField.Value, app.xEditField.Value, app.vEditField.Value, app.wEditField.Value, PPGAnal.total_seg_idx, ...
                app.aEditField.Value, app.bEditField.Value, app.cEditField.Value, app.dEditField.Value, app.eEditField.Value, app.fEditField.Value};

            if any(cellfun(@isempty, updateValues))
                msgbox('Cannot update values if any are missing', 'Error', 'error')
            else
                %%Value update for PPG
                PPGAnal.OnSpDnDpOff = [PPGAnal.seg(app.OnEditField.Value) PPGAnal.seg(app.SpEditField.Value) ...
                                       PPGAnal.seg(app.DnEditField.Value) PPGAnal.seg(app.DpEditField.Value) ...
                                       PPGAnal.seg(app.OffEditField.Value)];
    
                %Value update for VPG
                PPGAnal.uxvw = [PPGAnal.VPG(app.uEditField.Value) PPGAnal.VPG(app.xEditField.Value) ...
                                PPGAnal.VPG(app.vEditField.Value) PPGAnal.VPG(app.wEditField.Value)];
    
                %value update for APG
                PPGAnal.c_d_APG(PPGAnal.total_seg_idx) = app.canddpresentEditField.Value;
                PPGAnal.abcdef = [PPGAnal.APG(app.aEditField.Value) PPGAnal.APG(app.bEditField.Value) ...
                    PPGAnal.APG(app.cEditField.Value) PPGAnal.APG(app.dEditField.Value) ...
                    PPGAnal.APG(app.eEditField.Value) PPGAnal.APG(app.fEditField.Value)];
                
                %time variables of all waveform
                PPGAnal.OnSpDnDpOff_time  = [app.OnEditField.Value app.SpEditField.Value ...
                                             app.DnEditField.Value app.DpEditField.Value ...
                                             app.OffEditField.Value];
                PPGAnal.uxvw_time  = [app.uEditField.Value app.xEditField.Value ...
                                      app.vEditField.Value app.wEditField.Value];
                PPGAnal.abcdef_time = [app.aEditField.Value app.bEditField.Value ...
                                       app.cEditField.Value app.dEditField.Value ...
                                       app.eEditField.Value app.fEditField.Value];
                
                result = PPGAnal.UpdateFeatures();
    
                if (~result)
                    msgbox('Could not calculate extra features for updated values', 'Error', 'error')
                end
    
                %update the figues
                app.PlotFiducial(PPGAnal);
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            if (app.PPGAnalObject.Next())

                %alert on 2nd last data
                if (app.PPGAnalObject.entry_idx+1 == app.PPGAnalObject.size_data(1))
                msgbox("This is second last data","Alert","warn");
                end
    
                %autosave onlast data
                if (app.PPGAnalObject.entry_idx > app.PPGAnalObject.size_data(1))
                msgbox("Auto saving","Alert","warn");
                SavematButtonPushed(app, event)
                ExitButtonPushed(app, event)
                end
    
                app.PlotPPG();

                app.PlotpressedLamp.Color = 'w'; %to show user that the plot button has not been pressed
            end
        end

        % Button pushed function: ProcessAllButton
        function ProcessAllButtonPushed(app, event)
            app.PPGAnalObject.ProcessDataset();
            app.PlotPPG();
            app.PlotFiducial(app.PPGAnalObject);
        end

        % Button pushed function: SavematButton
        function SavematButtonPushed(app, event)
            app.PPGAnalObject.GenerateOutput(true);
        end

        % Button pushed function: SavecsvButton
        function SavecsvButtonPushed(app, event)
            app.PPGAnalObject.GenerateOutput(false);
        end

        % Callback function
        function PPGFeatUIFigureThemeChanged(app, event)
            app_color = [0.94 0.94 0.94];
            panel_color = [0.8 0.8 0.8];
            panel2_color = [0.65 0.65 0.65];

            if app.PPGFeatUIFigure.Theme.BaseColorStyle == "light"
                app.PPGFeatUIFigure.Color = app_color;
                app.Panel.BackgroundColor = panel_color;
                app.InputPanel.BackgroundColor = panel2_color;
            elseif app.PPGFeatUIFigure.Theme.BaseColorStyle == "dark"
                app.PPGFeatUIFigure.Color = fliplightness(app_color);
                app.Panel.BackgroundColor = fliplightness(panel_color);
                app.InputPanel.BackgroundColor = fliplightness(panel2_color);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PPGFeatUIFigure = uifigure('Visible', 'off');
            app.PPGFeatUIFigure.Name = 'PPGFeat';
            app.GridLayout = uigridlayout(app.PPGFeatUIFigure);
            app.JPG_Panel = uipanel(app.GridLayout);
            app.JPG_GridLayout = uigridlayout(app.JPG_Panel);
            app.UIAxes3 = uiaxes(app.JPG_GridLayout);
            title(app.UIAxes3, 'JPG')
            zlabel(app.UIAxes3, 'Z')
            app.APG_Panel = uipanel(app.GridLayout);
            app.APG_GridLayout = uigridlayout(app.APG_Panel);
            app.UIAxes5 = uiaxes(app.APG_GridLayout);
            title(app.UIAxes5, 'APG')
            zlabel(app.UIAxes5, 'Z')
            app.canddpresentEditFieldLabel = uilabel(app.APG_GridLayout);
            app.canddpresentEditFieldLabel.Text = 'c and d present ';
            app.canddpresentEditField = uieditfield(app.APG_GridLayout, 'numeric');
            app.Yes0NoLabel = uilabel(app.APG_GridLayout);
            app.Yes0NoLabel.Text = '1: Yes, 0: No';
            app.aEditFieldLabel = uilabel(app.APG_GridLayout);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.APG_GridLayout, 'numeric');
            app.aEditField.AllowEmpty = 'on';
            app.bEditFieldLabel = uilabel(app.APG_GridLayout);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.APG_GridLayout, 'numeric');
            app.bEditField.AllowEmpty = 'on';
            app.eEditFieldLabel = uilabel(app.APG_GridLayout);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.APG_GridLayout, 'numeric');
            app.eEditField.AllowEmpty = 'on';
            app.fEditFieldLabel = uilabel(app.APG_GridLayout);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.APG_GridLayout, 'numeric');
            app.fEditField.AllowEmpty = 'on';
            app.cEditField = uieditfield(app.APG_GridLayout, 'numeric');
            app.cEditField.AllowEmpty = 'on';
            app.cmLabel = uilabel(app.APG_GridLayout);
            app.cmLabel.Text = 'c ';
            app.dEditField = uieditfield(app.APG_GridLayout, 'numeric');
            app.dEditField.AllowEmpty = 'on';
            app.dEditFieldLabel = uilabel(app.APG_GridLayout);
            app.dEditFieldLabel.Text = 'd ';
            app.fLabel = uilabel(app.APG_GridLayout);
            app.fLabel.Text = 'f ';
            app.VPG_Panel = uipanel(app.GridLayout);
            app.VPG_GirdLayout = uigridlayout(app.VPG_Panel);
            app.UIAxes6 = uiaxes(app.VPG_GirdLayout);
            title(app.UIAxes6, 'VPG')
            zlabel(app.UIAxes6, 'Z')
            app.uEditField = uieditfield(app.VPG_GirdLayout, 'numeric');
            app.uEditField.AllowEmpty = 'on';
            app.xLabel = uilabel(app.VPG_GirdLayout);
            app.xLabel.Text = 'x';
            app.xEditField = uieditfield(app.VPG_GirdLayout, 'numeric');
            app.xEditField.AllowEmpty = 'on';
            app.yLabel = uilabel(app.VPG_GirdLayout);
            app.yLabel.Text = 'v';
            app.vEditField = uieditfield(app.VPG_GirdLayout, 'numeric');
            app.vEditField.AllowEmpty = 'on';
            app.zLabel = uilabel(app.VPG_GirdLayout);
            app.zLabel.Text = 'w';
            app.wEditField = uieditfield(app.VPG_GirdLayout, 'numeric');
            app.wEditField.AllowEmpty = 'on';
            app.uLabel = uilabel(app.VPG_GirdLayout);
            app.uLabel.Text = 'u';
            app.PPG_Panel = uipanel(app.GridLayout);
            app.PPG_GridLayout = uigridlayout(app.PPG_Panel);
            app.UIAxes2 = uiaxes(app.PPG_GridLayout);
            title(app.UIAxes2, 'PPG Segment')
            zlabel(app.UIAxes2, 'Z')
            app.SLabel_2 = uilabel(app.PPG_GridLayout);
            app.SLabel_2.Text = 'off';
            app.OffEditField = uieditfield(app.PPG_GridLayout, 'numeric');
            app.OffEditField.AllowEmpty = 'on';
            app.onLabel = uilabel(app.PPG_GridLayout);
            app.onLabel.Text = 'on';
            app.OnEditField = uieditfield(app.PPG_GridLayout, 'numeric');
            app.OnEditField.AllowEmpty = 'on';
            app.DLabel = uilabel(app.PPG_GridLayout);
            app.DLabel.Text = 'sp';
            app.SpEditField = uieditfield(app.PPG_GridLayout, 'numeric');
            app.SpEditField.AllowEmpty = 'on';
            app.dnLabel = uilabel(app.PPG_GridLayout);
            app.dnLabel.Text = 'dn';
            app.DnEditField = uieditfield(app.PPG_GridLayout, 'numeric');
            app.DnEditField.AllowEmpty = 'on';
            app.SLabel = uilabel(app.PPG_GridLayout);
            app.SLabel.Text = 'dp';
            app.DpEditField = uieditfield(app.PPG_GridLayout, 'numeric');
            app.DpEditField.AllowEmpty = 'on';
            app.FilteredPPG_Panel = uipanel(app.GridLayout);
            app.FilteredPPG_GridLayout = uigridlayout(app.FilteredPPG_Panel);
            app.UIAxes = uiaxes(app.FilteredPPG_GridLayout);
            title(app.UIAxes, 'Filtered PPG')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Normalized values')
            zlabel(app.UIAxes, 'Z')
            app.Min2EditField = uieditfield(app.FilteredPPG_GridLayout, 'numeric');
            app.Min2EditFieldLabel = uilabel(app.FilteredPPG_GridLayout);
            app.Min2EditFieldLabel.Text = 'Min2';
            app.Min1EditField = uieditfield(app.FilteredPPG_GridLayout, 'numeric');
            app.Min1EditFieldLabel = uilabel(app.FilteredPPG_GridLayout);
            app.Min1EditFieldLabel.Text = 'Min1';
            app.SegmentTextAreaLabel = uilabel(app.FilteredPPG_GridLayout);
            app.SegmentTextAreaLabel.Text = 'Segment';
            app.SegmentTextArea = uitextarea(app.FilteredPPG_GridLayout);
            app.SegmentTextArea.Editable = 'off';
            app.EntryEditFieldLabel = uilabel(app.FilteredPPG_GridLayout);
            app.EntryEditFieldLabel.Text = 'Entry';
            app.EntryEditField = uieditfield(app.FilteredPPG_GridLayout, 'text');
            app.EntryEditField.Editable = 'off';
            app.RawPPG_Panel = uipanel(app.GridLayout);
            app.RawPPG_GridLayout = uigridlayout(app.RawPPG_Panel);
            app.UIAxes4 = uiaxes(app.RawPPG_GridLayout);
            title(app.UIAxes4, 'RAW PPG')
            xlabel(app.UIAxes4, 'Samples')
            ylabel(app.UIAxes4, 'Amplitude')
            zlabel(app.UIAxes4, 'Z')
            app.Panel = uipanel(app.GridLayout);
            app.PanelGridLayout = uigridlayout(app.Panel);
            app.InputPanel = uipanel(app.PanelGridLayout);
            app.InputPanelGridLayout = uigridlayout(app.InputPanel);
            app.HzLabel = uilabel(app.InputPanelGridLayout);
            app.HzLabel.Text = 'Hz';
            app.sLabel = uilabel(app.InputPanelGridLayout);
            app.sLabel.Text = 's';
            app.EnterSamplingfreqfilterfreqLabel = uilabel(app.InputPanelGridLayout);
            app.EnterSamplingfreqfilterfreqLabel.Text = '  Enter Sampling freq & filter freq';
            app.ResampleCheck = uicheckbox(app.InputPanelGridLayout);
            app.ResampleCheck.Text = '';
            app.SegmentCheck = uicheckbox(app.InputPanelGridLayout);
            app.SegmentCheck.Text = '';
            app.SamplingfreqEditFieldLabel = uilabel(app.InputPanelGridLayout);
            app.SamplingfreqEditFieldLabel.Text = 'Sampling freq';
            app.SamplingfreqEditField = uieditfield(app.InputPanelGridLayout, 'numeric');
            app.SamplingfreqEditField.Value = 1000;
            app.F1Label = uilabel(app.InputPanelGridLayout);
            app.F1Label.Text = 'FL';
            app.FLEditField = uieditfield(app.InputPanelGridLayout, 'numeric');
            app.FLEditField.Value = 0.4;
            app.FHEditFieldLabel = uilabel(app.InputPanelGridLayout);
            app.FHEditFieldLabel.Text = 'FH';
            app.FHEditField = uieditfield(app.InputPanelGridLayout, 'numeric');
            app.FHEditField.Value = 8;
            app.ResampleEditFieldLabel = uilabel(app.InputPanelGridLayout);
            app.ResampleEditFieldLabel.Text = 'Resample';
            app.ResampleEditField = uieditfield(app.InputPanelGridLayout, 'numeric');
            app.ResampleEditField.Value = 1000;
            app.SegmentEditFieldLabel = uilabel(app.InputPanelGridLayout);
            app.SegmentEditFieldLabel.Text = 'Segment';
            app.SegmentEditField = uieditfield(app.InputPanelGridLayout, 'numeric');
            app.SegmentEditField.Value = 10;
            app.LoadFileButton = uibutton(app.InputPanelGridLayout, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.LoadFolderButton = uibutton(app.InputPanelGridLayout, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.HzLabel_2 = uilabel(app.InputPanelGridLayout);
            app.HzLabel_2.Text = 'Hz';
            app.InvertCheck = uicheckbox(app.InputPanelGridLayout);
            app.InvertCheck.Text = '';
            app.InvertLabel = uilabel(app.InputPanelGridLayout);
            app.InvertLabel.Text = 'Invert';
            app.PlotButton = uibutton(app.PanelGridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Plotallwaveformsusingmin1andmin2Label = uilabel(app.PanelGridLayout);
            app.Plotallwaveformsusingmin1andmin2Label.Text = {'Plot all waveforms using '; 'min1 and min2'};
            app.UpdateButton = uibutton(app.PanelGridLayout, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.UpdatefeaturepointsusingmodifieduserdefinedvaluesLabel = uilabel(app.PanelGridLayout);
            app.UpdatefeaturepointsusingmodifieduserdefinedvaluesLabel.Text = {'Update feature points using'; 'modified user defined values'};
            app.NextButton = uibutton(app.PanelGridLayout, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.GotonextwaveformLabel = uilabel(app.PanelGridLayout);
            app.GotonextwaveformLabel.Text = 'Go to next waveform ';
            app.SavematButton = uibutton(app.PanelGridLayout, 'push');
            app.SavematButton.ButtonPushedFcn = createCallbackFcn(app, @SavematButtonPushed, true);
            app.SavematButton.Text = 'Save .mat';
            app.CycleSQILabel = uilabel(app.PanelGridLayout);
            app.CycleSQILabel.Text = 'Cycle SQI';
            app.ProcessAllButton = uibutton(app.PanelGridLayout, 'push');
            app.ProcessAllButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAllButtonPushed, true);
            app.ProcessAllButton.Text = 'Process every entry';
            app.ProcesseveryentrywithoutmanualinputLabel = uilabel(app.PanelGridLayout);
            app.ProcesseveryentrywithoutmanualinputLabel.Text = {'Process every entry without '; 'manual input'};
            app.SavecsvButton = uibutton(app.PanelGridLayout, 'push');
            app.SavecsvButton.ButtonPushedFcn = createCallbackFcn(app, @SavecsvButtonPushed, true);
            app.SavecsvButton.Text = 'Save .csv';
            app.GenerateoutputLabel = uilabel(app.PanelGridLayout);
            app.GenerateoutputLabel.Text = 'Generate output';
            app.SegmentSQILabel = uilabel(app.PanelGridLayout);
            app.SegmentSQILabel.Text = 'Segment SQI';
            app.PlotpressedLampLabel = uilabel(app.PanelGridLayout);
            app.PlotpressedLampLabel.Text = 'Plot pressed';
            app.PlotpressedLamp = uilamp(app.PanelGridLayout);
            app.CorrelationLabel = uilabel(app.PanelGridLayout);
            app.CorrelationLabel.Text = 'Correlation';
            app.CycleCorrSQIEditField = uieditfield(app.PanelGridLayout, 'numeric');
            app.CycleCorrSQIEditField.Editable = 'off';
            app.SkewnessLabel = uilabel(app.PanelGridLayout);
            app.SkewnessLabel.Text = 'Skewness';
            app.CycleSkewSQIEditField = uieditfield(app.PanelGridLayout, 'numeric');
            app.CycleSkewSQIEditField.Editable = 'off';
            app.CorrelationLabel_2 = uilabel(app.PanelGridLayout);
            app.CorrelationLabel_2.Text = 'Combined';
            app.CycleCombSQIEditField = uieditfield(app.PanelGridLayout, 'numeric');
            app.CycleCombSQIEditField.Editable = 'off';
            app.CorrelationLabel_3 = uilabel(app.PanelGridLayout);
            app.CorrelationLabel_3.Text = 'Correlation';
            app.SegCorrSQIEditField = uieditfield(app.PanelGridLayout, 'numeric');
            app.SegCorrSQIEditField.Editable = 'off';
            app.SkewnessLabel_2 = uilabel(app.PanelGridLayout);
            app.SkewnessLabel_2.Text = 'Skewness';
            app.SegSkewSQIEditField = uieditfield(app.PanelGridLayout, 'numeric');
            app.SegSkewSQIEditField.Editable = 'off';
            app.CorrelationLabel_4 = uilabel(app.PanelGridLayout);
            app.CorrelationLabel_4.Text = 'Combined';
            app.SegCombSQIEdit = uieditfield(app.PanelGridLayout, 'numeric');
            app.SegCombSQIEdit.Editable = 'off';
            % Show the figure after all components are created
            app.PPGFeatUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PPGFeat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PPGFeatUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PPGFeatUIFigure)
        end
    end
end